classdef TwoD_filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        DfilterPanel           matlab.ui.container.Panel
        rowsEditFieldLabel     matlab.ui.control.Label
        rowsEditField          matlab.ui.control.NumericEditField
        columnsEditFieldLabel  matlab.ui.control.Label
        columnsEditField       matlab.ui.control.NumericEditField
        YesButton              matlab.ui.control.StateButton
        ButtonGroup            matlab.ui.container.ButtonGroup
        meanfilterButton       matlab.ui.control.RadioButton
        medianfilterButton     matlab.ui.control.RadioButton
        f_kfilterPanel         matlab.ui.container.Panel
        LgmEditFieldLabel      matlab.ui.control.Label
        LgmEditField           matlab.ui.control.NumericEditField
        LsmEditFieldLabel      matlab.ui.control.Label
        LsmEditField           matlab.ui.control.NumericEditField
        YesButton_2            matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        callingapp % Description 保存主app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.callingapp = mainapp; %保存主app
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.TwoDfilterMenu.Enable = 'on';%开启主app二维滤波模块
            delete(app)
        end

        % Value changed function: YesButton
        function YesButtonValueChanged(app, event)
            if app.medianfilterButton.Value
                Processing_Plus(app.callingapp,1,app.DfilterPanel.Title,app.medianfilterButton.Text,app.rowsEditField.Value,app.columnsEditField.Value,0,0,0,0,0);
            elseif app.meanfilterButton.Value
                Processing_Plus(app.callingapp,1,app.DfilterPanel.Title,app.meanfilterButton.Text,app.rowsEditField.Value,app.columnsEditField.Value,0,0,0,0,0);
            end
        end

        % Value changed function: YesButton_2
        function YesButton_2ValueChanged(app, event)
            Processing_Plus(app.callingapp,1,app.f_kfilterPanel.Title,app.LgmEditField.Value,app.LsmEditField.Value,0,0,0,0,0,0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'face.jpg';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DfilterPanel = uipanel(app.UIFigure);
            app.DfilterPanel.Tooltip = {'2维滤波，常用于去除高频噪声，平滑整个图像。由于要采用模板进行运算，对于雷达数据来说，效率较低。'};
            app.DfilterPanel.Title = '2D filter';
            app.rowsEditFieldLabel = uilabel(app.DfilterPanel);
            app.rowsEditFieldLabel.Text = 'rows';
            app.rowsEditField = uieditfield(app.DfilterPanel, 'numeric');
            app.rowsEditField.Tooltip = {'滤波模板的行，大于等于3，越大执行时间越久。'};
            app.rowsEditField.Value = 5;
            app.columnsEditFieldLabel = uilabel(app.DfilterPanel);
            app.columnsEditFieldLabel.Text = 'columns';
            app.columnsEditField = uieditfield(app.DfilterPanel, 'numeric');
            app.columnsEditField.Tooltip = {'滤波模板的列，大于等于3，越大执行越久。'};
            app.columnsEditField.Value = 5;
            app.YesButton = uibutton(app.DfilterPanel, 'state');
            app.YesButton.ValueChangedFcn = createCallbackFcn(app, @YesButtonValueChanged, true);
            app.YesButton.Text = 'Yes';
            app.ButtonGroup = uibuttongroup(app.DfilterPanel);
            app.meanfilterButton = uiradiobutton(app.ButtonGroup);
            app.meanfilterButton.Text = 'mean filter';
            app.medianfilterButton = uiradiobutton(app.ButtonGroup);
            app.medianfilterButton.Text = 'median filter';
            app.medianfilterButton.Value = true;
            app.f_kfilterPanel = uipanel(app.UIFigure);
            app.f_kfilterPanel.Tooltip = {'2维滤波，一半用于去除高频噪声，平滑整个图像。由于要采用模板进行运算，对于雷达数据来说，效率较低。'};
            app.f_kfilterPanel.Title = 'f_k filter';
            app.LgmEditFieldLabel = uilabel(app.f_kfilterPanel);
            app.LgmEditFieldLabel.Text = 'Lg(m)';
            app.LgmEditField = uieditfield(app.f_kfilterPanel, 'numeric');
            app.LgmEditField.Tooltip = {'低频扰动窗口长度'};
            app.LgmEditField.Value = 40;
            app.LsmEditFieldLabel = uilabel(app.f_kfilterPanel);
            app.LsmEditFieldLabel.Text = 'Ls(m)';
            app.LsmEditField = uieditfield(app.f_kfilterPanel, 'numeric');
            app.LsmEditField.Tooltip = {'设备微抖动最大位移'};
            app.LsmEditField.Value = 0.2;
            app.YesButton_2 = uibutton(app.f_kfilterPanel, 'state');
            app.YesButton_2.ValueChangedFcn = createCallbackFcn(app, @YesButton_2ValueChanged, true);
            app.YesButton_2.Text = 'Yes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TwoD_filter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end